classdef Flight_Data_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        PredictButton                matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        SearchFileButton             matlab.ui.control.Button
        TrueAirSpeedThresholdSlider  matlab.ui.control.Slider
        TrueAirSpeedThresholdLabel   matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        FlightPathLatitudePositionvsLongitudePositionPanel  matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        FlightData % Imported data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchFileButton
        function SearchFileButtonPushed(app, event)
            file = uigetfile;
            app.Label.Text = file;
        end

        % Value changed function: TrueAirSpeedThresholdSlider
        function TrueAirSpeedThresholdSliderValueChanged(app, event)
            Value = app.TrueAirSpeedThresholdSlider.Value;  
            app.FlightData = importFlightData("FlightData.xlsx");
            % perform simple clean up of stationary ground data
            app.FlightData = app.FlightData(app.FlightData.TrueAirSpeed >= Value,:);
            
            % Create geoplot of FlightData.LatitudePosition and FlightData.LongitudePosition
            gx = geoaxes(app.FlightPathLatitudePositionvsLongitudePositionPanel);
            geoplot(gx,app.FlightData.LatitudePosition,app.FlightData.LongitudePosition,"LineWidth",2,"Color","red");
            % Add title
            gx.Basemap = 'colorterrain'; 
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            %Clean oil sensor data
            FlightDataClean = cleanOilSensors(app.FlightData);
            rng(1) % reproducibility
            mdl = trainTASModel(FlightDataClean);
            PredictedAirSpeed = mdl.predictFcn(FlightDataClean);
            % Create plot of FlightDataClean.Time and FlightDataClean.TrueAirSpeed
            plot(app.UIAxes,FlightDataClean.Time,FlightDataClean.TrueAirSpeed,"DisplayName","TrueAirSpeed");
            % Create plot of FlightDataClean.Time and PredictedAirSpeed
            hold(app.UIAxes,"on")
            plot(app.UIAxes,FlightDataClean.Time,PredictedAirSpeed,"DisplayName","PredictedAirSpeed");
            legend(app.UIAxes,'show');
            hold(app.UIAxes,"off")
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
          
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TrueAirSpeedThresholdLabel = uilabel(app.LeftPanel);
            app.TrueAirSpeedThresholdLabel.Text = 'TrueAirSpeed Threshold';
            app.TrueAirSpeedThresholdSlider = uislider(app.LeftPanel);
            app.TrueAirSpeedThresholdSlider.Limits = [0 400];
            app.TrueAirSpeedThresholdSlider.MajorTicks = [0 100 200 300 400];
            app.TrueAirSpeedThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @TrueAirSpeedThresholdSliderValueChanged, true);
            app.SearchFileButton = uibutton(app.LeftPanel, 'push');
            app.SearchFileButton.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButtonPushed, true);
            app.SearchFileButton.Text = 'Search File';
            app.Label = uilabel(app.LeftPanel);
            app.PredictButton = uibutton(app.LeftPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Plane image.jpg');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.FlightPathLatitudePositionvsLongitudePositionPanel = uipanel(app.RightPanel);
            app.FlightPathLatitudePositionvsLongitudePositionPanel.Title = 'Flight Path - LatitudePosition vs. LongitudePosition';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Flight_Data_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end